classdef PRACTICA1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        SEAL1Tab           matlab.ui.container.Tab
        EJECUTARButton     matlab.ui.control.StateButton
        TiempoensegundosamostrarSlider_2  matlab.ui.control.Slider
        TiempoensegundosamostrarSlider_2Label  matlab.ui.control.Label
        Label              matlab.ui.control.Label
        SEAL1Label         matlab.ui.control.Label
        grafica1           matlab.ui.control.UIAxes
        SEAL2Tab           matlab.ui.container.Tab
        EJECUTARButton_2   matlab.ui.control.StateButton
        segundos2          matlab.ui.control.Slider
        TiempoensegundosamostrarSliderLabel  matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        SEAL2Label         matlab.ui.control.Label
        grafica2           matlab.ui.control.UIAxes
        SEAL3Tab           matlab.ui.container.Tab
        SEAL3Label         matlab.ui.control.Label
        SEAL4Tab           matlab.ui.container.Tab
        EJECUTARButton_4   matlab.ui.control.StateButton
        segundos3          matlab.ui.control.Slider
        TiempoensegundosamostrarSliderLabel_2  matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        SEAL4Label         matlab.ui.control.Label
        grafica4           matlab.ui.control.UIAxes
        SEAL5Tab           matlab.ui.container.Tab
        EJECUTARButton_5   matlab.ui.control.StateButton
        segundos5          matlab.ui.control.Slider
        TiempoensegundosamostrarSliderLabel_3  matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        SEAL5Label         matlab.ui.control.Label
        grafica5           matlab.ui.control.UIAxes
        SEAL6Tab           matlab.ui.container.Tab
        EJECUTARButton_6   matlab.ui.control.StateButton
        Label_6            matlab.ui.control.Label
        SEAL6Label         matlab.ui.control.Label
        grafica6           matlab.ui.control.UIAxes
        SEAL7Tab           matlab.ui.container.Tab
        EJECUTARButton_7   matlab.ui.control.StateButton
        Label_7            matlab.ui.control.Label
        SEAL7Label         matlab.ui.control.Label
        grafica7           matlab.ui.control.UIAxes
        SEAL8Tab           matlab.ui.container.Tab
        radio              matlab.ui.control.Slider
        CargaQ1Label_3     matlab.ui.control.Label
        EJECUTARButton_8   matlab.ui.control.StateButton
        carga2             matlab.ui.control.Slider
        CargaQ1Label_2     matlab.ui.control.Label
        carga1             matlab.ui.control.Slider
        CargaQ1Label       matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        SEAL8Label         matlab.ui.control.Label
        grafica8           matlab.ui.control.UIAxes
        SEAL9Tab           matlab.ui.container.Tab
        EJECUTARButton_9   matlab.ui.control.StateButton
        Label_8            matlab.ui.control.Label
        SEAL9Label         matlab.ui.control.Label
        grafica9           matlab.ui.control.UIAxes
        SEAL10Tab          matlab.ui.container.Tab
        EJECUTARButton_10  matlab.ui.control.StateButton
        Label_9            matlab.ui.control.Label
        SEAL10Label        matlab.ui.control.Label
        grafica10          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EJECUTARButton
        function EJECUTARButtonValueChanged(app, event)
            value = app.EJECUTARButton.Value;
            
            segundos = app.TiempoensegundosamostrarSlider_2.Value;

            x = linspace(0,segundos);
            y = 5 + 2*x;
            
            
            plot(app.grafica1,x,y)
           
        end

        % Value changed function: EJECUTARButton_2
        function EJECUTARButton_2ValueChanged(app, event)
            value = app.EJECUTARButton_2.Value;
            
            segundos2 = app.segundos2.Value;

            x = linspace(0,segundos2);
            y = 10*sin(2*pi*x);
            plot(app.grafica2,x,y)
            xlabel(app.grafica2,"Tiempo(variable independiente) en segundos");
            ylabel(app.grafica2,"Salida de una señal eléctrica con 10 VPP");
            title(app.grafica2,"Señal de salida de una fuente de corriente eléctrica alterna con 10 de Voltaje Pico a Pico");


        end

        % Value changed function: EJECUTARButton_4
        function EJECUTARButton_4ValueChanged(app, event)
            value = app.EJECUTARButton_4.Value;

            segundos4 = app.segundos3.Value;
            
            t = linspace(0,3*pi);
            x =  t.^2 + 1;
            xlabel(app.grafica4,"Tiempo (variable independiente) en segundos");
            ylabel(app.grafica4,"Salida de salida");
            title(app.grafica4,"Señal de salida de una fuente de corriente eléctrica directa");
            plot(app.grafica4,t/pi,x,'.-',t/pi,sin(t))
            
        end

        % Value changed function: EJECUTARButton_5
        function EJECUTARButton_5ValueChanged(app, event)
            value = app.EJECUTARButton_5.Value;

            segundos5 = app.segundos5.Value;
            
            x = linspace(0,segundos5);
            y = log(x);
            xlabel(app.grafica5,"Input (variable independiente)");
            ylabel(app.grafica5,"Tiempo de ejecución");
            title(app.grafica5,"Tiempo de ejecución de un algoritmo con complejidad logarítmica");
            plot(app.grafica5,x,y);
        end

        % Button down function: grafica6
        function grafica6ButtonDown(app, event)
            
        end

        % Value changed function: EJECUTARButton_6
        function EJECUTARButton_6ValueChanged(app, event)
            value = app.EJECUTARButton_6.Value;
            w=[0 1 0 1 0 0 0 0 1 0 0];
            x=[0 1 2 3 4 5 6 7 8 9 10];         
            plot(app.grafica6,x,w);
            xlabel(app.grafica6,'Letras en elefante');
            ylabel(app.grafica6,'letra e');
            title(app.grafica6,'Señal de la letra e');
        end

        % Value changed function: EJECUTARButton_7
        function EJECUTARButton_7ValueChanged(app, event)
            value = app.EJECUTARButton_7.Value;
            
            t=[0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            h= axes();
            
                C = handle.CurrentPoint;
                title(app.grafica7,gca, ['(X,Y) = (',num2str(C(1,1)), ',',num2str(C(1,2)), ')']);


        end

        % Value changed function: EJECUTARButton_8
        function EJECUTARButton_8ValueChanged(app, event)
            value = app.EJECUTARButton_8.Value;
            
            Q1=app.carga1.Value; 
            Q2=app.carga2.Value;
            r=app.radio.Value;
            k=9*10.^9 
            F=(k*Q1*Q2)/(r^2);


            x=-1:.4:1;  
            y=-1:.4:1;  
            [xx,yy] = meshgrid(x,y); 
            r = sqrt(xx.*xx + yy.*yy);  
            rrr= r.*r.*r;  
            Ex = xx./rrr;  
            Ey = yy./rrr;  
            quiver(app.grafica8,x, y, Ex, Ey);  
            xlabel(app.grafica8,'coordenadas en x');  
            ylabel(app.grafica8,'coordenadas en y');  
            title(app.grafica8,'CAMPO ELECTRICO')
        end

        % Value changed function: EJECUTARButton_9
        function EJECUTARButton_9ValueChanged(app, event)
            value = app.EJECUTARButton_9.Value;
            
            load sunspot.dat
            year = sunspot(:,1); 
            relNums = sunspot(:,2);
            plot(app.grafica9,year,relNums)
            xlabel(app.grafica9,'Year')
            ylabel(app.grafica9,'Sunspot Number')
            title(app.grafica9,'Find All Peaks')
        end

        % Value changed function: EJECUTARButton_10
        function EJECUTARButton_10ValueChanged(app, event)
            value = app.EJECUTARButton_10.Value;
            
            load noisyecg.mat
            

t = 1:length(noisyECG_withTrend);


            plot(app.grafica10,t,noisyECG_withTrend)
title(app.grafica10,'Signal with a Trend')
xlabel(app.grafica10,'Samples');
ylabel(app.grafica10,'Voltage(mV)')
legend(app.grafica10,'Noisy ECG Signal')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SEAL1Tab = uitab(app.TabGroup);
            app.SEAL1Tab.Title = 'SEÑAL 1';
            app.grafica1 = uiaxes(app.SEAL1Tab);
            title(app.grafica1, 'Variación de la velocidad del coche')
            xlabel(app.grafica1, 'Tiempo (en segundos)')
            ylabel(app.grafica1, 'Variación en un momento dado')
            zlabel(app.grafica1, 'Z')
            app.SEAL1Label = uilabel(app.SEAL1Tab);
            app.SEAL1Label.Text = 'SEÑAL 1';
            app.Label = uilabel(app.SEAL1Tab);
            app.Label.Text = {'Carro que acelera con una aceleración constante de 5 m/s2 y que tenía una velocidad de 5 m/s.'; 'V = Vi + a*t'};
            app.TiempoensegundosamostrarSlider_2Label = uilabel(app.SEAL1Tab);
            app.TiempoensegundosamostrarSlider_2Label.Text = 'Tiempo en segundos a mostrar';
            app.TiempoensegundosamostrarSlider_2 = uislider(app.SEAL1Tab);
            app.TiempoensegundosamostrarSlider_2.Limits = [0 10000];
            app.EJECUTARButton = uibutton(app.SEAL1Tab, 'state');
            app.EJECUTARButton.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButtonValueChanged, true);
            app.EJECUTARButton.Text = 'EJECUTAR';
            app.SEAL2Tab = uitab(app.TabGroup);
            app.SEAL2Tab.Title = 'SEÑAL 2';
            app.grafica2 = uiaxes(app.SEAL2Tab);
            title(app.grafica2, 'Señal de salida de una fuente de corriente eléctrica alterna con 10 de Voltaje Pico a Pico')
            xlabel(app.grafica2, 'Tiempo(variable independiente) en segundos')
            ylabel(app.grafica2, 'Salida de una señal eléctrica con 10 VPP')
            zlabel(app.grafica2, 'Z')
            app.SEAL2Label = uilabel(app.SEAL2Tab);
            app.SEAL2Label.Text = 'SEÑAL 2';
            app.Label_2 = uilabel(app.SEAL2Tab);
            app.Label_2.Text = 'Se analiza la salida de un generador de corriente alterna con una salida de 10 VPP.';
            app.TiempoensegundosamostrarSliderLabel = uilabel(app.SEAL2Tab);
            app.TiempoensegundosamostrarSliderLabel.Text = 'Tiempo en segundos a mostrar';
            app.segundos2 = uislider(app.SEAL2Tab);
            app.EJECUTARButton_2 = uibutton(app.SEAL2Tab, 'state');
            app.EJECUTARButton_2.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_2ValueChanged, true);
            app.EJECUTARButton_2.Text = 'EJECUTAR';
            app.SEAL3Tab = uitab(app.TabGroup);
            app.SEAL3Tab.Title = 'SEÑAL 3';
            app.SEAL3Label = uilabel(app.SEAL3Tab);
            app.SEAL3Label.Text = 'SEÑAL 3';
            app.SEAL4Tab = uitab(app.TabGroup);
            app.SEAL4Tab.Title = 'SEÑAL 4';
            app.grafica4 = uiaxes(app.SEAL4Tab);
            title(app.grafica4, ' ')
            xlabel(app.grafica4, ' ')
            ylabel(app.grafica4, ' ')
            zlabel(app.grafica4, 'Z')
            app.SEAL4Label = uilabel(app.SEAL4Tab);
            app.SEAL4Label.Text = 'SEÑAL 4';
            app.Label_3 = uilabel(app.SEAL4Tab);
            app.Label_3.Text = 'Salida de un circuito lógico.';
            app.TiempoensegundosamostrarSliderLabel_2 = uilabel(app.SEAL4Tab);
            app.TiempoensegundosamostrarSliderLabel_2.Text = 'Tiempo en segundos a mostrar';
            app.segundos3 = uislider(app.SEAL4Tab);
            app.EJECUTARButton_4 = uibutton(app.SEAL4Tab, 'state');
            app.EJECUTARButton_4.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_4ValueChanged, true);
            app.EJECUTARButton_4.Text = 'EJECUTAR';
            app.SEAL5Tab = uitab(app.TabGroup);
            app.SEAL5Tab.Title = 'SEÑAL 5';
            app.grafica5 = uiaxes(app.SEAL5Tab);
            title(app.grafica5, ' ')
            xlabel(app.grafica5, ' ')
            ylabel(app.grafica5, ' ')
            zlabel(app.grafica5, 'Z')
            app.SEAL5Label = uilabel(app.SEAL5Tab);
            app.SEAL5Label.Text = 'SEÑAL 5';
            app.Label_5 = uilabel(app.SEAL5Tab);
            app.Label_5.Text = 'Tiempo de ejecución de un algoritmo con complejidad O(log(n))';
            app.TiempoensegundosamostrarSliderLabel_3 = uilabel(app.SEAL5Tab);
            app.TiempoensegundosamostrarSliderLabel_3.Text = 'Tiempo en segundos a mostrar';
            app.segundos5 = uislider(app.SEAL5Tab);
            app.EJECUTARButton_5 = uibutton(app.SEAL5Tab, 'state');
            app.EJECUTARButton_5.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_5ValueChanged, true);
            app.EJECUTARButton_5.Text = 'EJECUTAR';
            app.SEAL6Tab = uitab(app.TabGroup);
            app.SEAL6Tab.Title = 'SEÑAL 6';
            app.grafica6 = uiaxes(app.SEAL6Tab);
            title(app.grafica6, ' ')
            xlabel(app.grafica6, ' ')
            ylabel(app.grafica6, ' ')
            zlabel(app.grafica6, 'Z')
            app.grafica6.ButtonDownFcn = createCallbackFcn(app, @grafica6ButtonDown, true);
            app.SEAL6Label = uilabel(app.SEAL6Tab);
            app.SEAL6Label.Text = 'SEÑAL 6';
            app.Label_6 = uilabel(app.SEAL6Tab);
            app.Label_6.Text = 'Señal que envia la tecla ‘e’ al escribir la palabra ‘elefante’ en el teclado ';
            app.EJECUTARButton_6 = uibutton(app.SEAL6Tab, 'state');
            app.EJECUTARButton_6.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_6ValueChanged, true);
            app.EJECUTARButton_6.Text = 'EJECUTAR';
            app.SEAL7Tab = uitab(app.TabGroup);
            app.SEAL7Tab.Title = 'SEÑAL 7';
            app.grafica7 = uiaxes(app.SEAL7Tab);
            title(app.grafica7, ' ')
            xlabel(app.grafica7, ' ')
            ylabel(app.grafica7, ' ')
            zlabel(app.grafica7, 'Z')
            app.SEAL7Label = uilabel(app.SEAL7Tab);
            app.SEAL7Label.Text = 'SEÑAL 7';
            app.Label_7 = uilabel(app.SEAL7Tab);
            app.Label_7.Text = 'Ubicación del puntero';
            app.EJECUTARButton_7 = uibutton(app.SEAL7Tab, 'state');
            app.EJECUTARButton_7.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_7ValueChanged, true);
            app.EJECUTARButton_7.Text = 'EJECUTAR';
            app.SEAL8Tab = uitab(app.TabGroup);
            app.SEAL8Tab.Title = 'SEÑAL 8';
            app.grafica8 = uiaxes(app.SEAL8Tab);
            title(app.grafica8, ' ')
            xlabel(app.grafica8, ' ')
            ylabel(app.grafica8, ' ')
            zlabel(app.grafica8, 'Z')
            app.SEAL8Label = uilabel(app.SEAL8Tab);
            app.SEAL8Label.Text = 'SEÑAL 8';
            app.Label_4 = uilabel(app.SEAL8Tab);
            app.Label_4.Text = 'Aplicación de la Ley de Coulomb';
            app.CargaQ1Label = uilabel(app.SEAL8Tab);
            app.CargaQ1Label.Text = 'Radio';
            app.carga1 = uislider(app.SEAL8Tab);
            app.CargaQ1Label_2 = uilabel(app.SEAL8Tab);
            app.CargaQ1Label_2.Text = 'Carga Q1';
            app.carga2 = uislider(app.SEAL8Tab);
            app.EJECUTARButton_8 = uibutton(app.SEAL8Tab, 'state');
            app.EJECUTARButton_8.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_8ValueChanged, true);
            app.EJECUTARButton_8.Text = 'EJECUTAR';
            app.CargaQ1Label_3 = uilabel(app.SEAL8Tab);
            app.CargaQ1Label_3.Text = 'Carga Q2';
            app.radio = uislider(app.SEAL8Tab);
            app.SEAL9Tab = uitab(app.TabGroup);
            app.SEAL9Tab.Title = 'SEÑAL 9';
            app.grafica9 = uiaxes(app.SEAL9Tab);
            title(app.grafica9, ' ')
            xlabel(app.grafica9, ' ')
            ylabel(app.grafica9, ' ')
            zlabel(app.grafica9, 'Z')
            app.SEAL9Label = uilabel(app.SEAL9Tab);
            app.SEAL9Label.Text = 'SEÑAL 9';
            app.Label_8 = uilabel(app.SEAL9Tab);
            app.Label_8.Text = 'El número relativo de manchas solares en Zúrich mide tanto el número como el tamaño de las manchas solares. ';
            app.EJECUTARButton_9 = uibutton(app.SEAL9Tab, 'state');
            app.EJECUTARButton_9.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_9ValueChanged, true);
            app.EJECUTARButton_9.Text = 'EJECUTAR';
            app.SEAL10Tab = uitab(app.TabGroup);
            app.SEAL10Tab.Title = 'SEÑAL 10';
            app.grafica10 = uiaxes(app.SEAL10Tab);
            title(app.grafica10, ' ')
            xlabel(app.grafica10, ' ')
            ylabel(app.grafica10, ' ')
            zlabel(app.grafica10, 'Z')
            app.SEAL10Label = uilabel(app.SEAL10Tab);
            app.SEAL10Label.Text = 'SEÑAL 10';
            app.Label_9 = uilabel(app.SEAL10Tab);
            app.Label_9.Text = 'Análisis de los picos en una señal de ECG (electrocardiograma). Un ECG es una medida de la actividad eléctrica del corazón en el tiempo.';
            app.EJECUTARButton_10 = uibutton(app.SEAL10Tab, 'state');
            app.EJECUTARButton_10.ValueChangedFcn = createCallbackFcn(app, @EJECUTARButton_10ValueChanged, true);
            app.EJECUTARButton_10.Text = 'EJECUTAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRACTICA1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end