classdef examen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        IntervaloEditField        matlab.ui.control.NumericEditField
        IntervaloEditFieldLabel   matlab.ui.control.Label
        GraficarSealButton        matlab.ui.control.Button
        PeriodoEditField          matlab.ui.control.NumericEditField
        PeriodoEditFieldLabel     matlab.ui.control.Label
        AmplitudEditField         matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel    matlab.ui.control.Label
        GraficacinButtonGroup     matlab.ui.container.ButtonGroup
        DiscretaButton            matlab.ui.control.RadioButton
        ContinuaButton            matlab.ui.control.RadioButton
        TipodesealDropDown        matlab.ui.control.DropDown
        TipodesealDropDownLabel   matlab.ui.control.Label
        GENERADORDESEALESLabel    matlab.ui.control.Label
        PRIMEREXAMENPARCIALLabel  matlab.ui.control.Label
        grafica                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarSealButton
        function GraficarSealButtonPushed(app, event)

            Amplitud = app.AmplitudEditField.Value
            Periodos = app.PeriodoEditField.Value
            Intervalo = app.IntervaloEditField.Value
            Tipo = app.GraficacinButtonGroup.SelectedObject.Text
            Sen = app.TipodesealDropDown.Value
            if Sen == 1
                title(app.grafica,'Escalón Unitario')

                
                A= 1;%Amplitud
                Fs = 10000;%Frecuencia deMuestreo
                t=0:1/Fs:Intervalo;%TiempodeSimulación
                x=A*heaviside(t);%GeneracióndeSeñal
                
                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[-1 Intervalo])
                ylim(app.grafica,[-1 A+.5]) %Grafica

            elseif Sen == 2
                title(app.grafica,'Escalón')
                A= Amplitud;%Amplitud
                Fs = 10000;%Frecuencia deMuestreo
                t=0:1/Fs:Intervalo;%TiempodeSimulación
                x=A*heaviside(t);%GeneracióndeSeñal
                
                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[-1 Intervalo])
                ylim(app.grafica,[-1 A+.5]) %Grafica
            elseif Sen == 3
                title(app.grafica,'Impulso Unitario')

               A=1;%Amplitud
               n=0:Intervalo;%TiempodeSimulación
               x = A.*[zeros(1,Periodos),ones(1,1),zeros(1,Intervalo-Periodos)]; 
                
                if(Tipo == 'Continua')
                  
                    plot(app.grafica,n,x)
                    
                else
                    
                    stem(app.grafica,n,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[-1 Intervalo])
                ylim(app.grafica,[0 1.1]) %Grafica
            elseif Sen == 4
                title(app.grafica,'Rampa Unitaria')
                A= 1;%Amplitud
                Fs = 10000;%Frecuenciade Muestreo
                t1=0:1/Fs:Periodos;
                t2=Periodos:1/Fs:Intervalo;
                r=t1;
                t=[t1,t2];%TiempodeSimulación
                x = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                
                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[-1 Intervalo])
                ylim(app.grafica,[0 Periodos+1]) %Grafica
            elseif Sen == 5
                title(app.grafica,'Señal Exponencial Compleja')
                
                Fs=10000;%FrecuenciadeMuestreo
                t=-Intervalo*pi:1/Fs:Intervalo*pi;%TiempodeSimulación
                x = exp(1j.*(2.*t+(pi/4))); 
              

                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[0 Intervalo])
            elseif Sen == 6
                title(app.grafica,'Señal Exponencial Real')
                Fs=10000;%FrecuenciadeMuestreo
                t = 0:1/Fs:Intervalo; %Tiempo de Simulación
                x=exp(t);%GeneracióndeSeñal

                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[0 Intervalo])
                
            
            elseif Sen == 7
                title(app.grafica,'Señal Cuadrada')
                A= Amplitud; %Amplitud
                T = Periodos; %Número de Periodos a Graficar
                f = 1/(Intervalo/T)
                duty= 50; %Ciclo útil
                t=0:Intervalo;%TiempodeSimulación
                x=A*square(2*pi*f*t,duty);%GeneracióndeSeñal

                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[0 Intervalo])
                ylim(app.grafica,[0-(A+.5) A+.5]) %Grafica
            elseif Sen == 8
                title(app.grafica,'Señal Diente de Sierra')
                
                A= Amplitud; %Amplitud
                T = Periodos; %Número de Periodos a Graficar
                f = 1/(Intervalo/T)
                duty= 50; %Ciclo útil
                t=0:Intervalo;%TiempodeSimulación
                x=A*sawtooth(2*pi*f*t,0.5);%GeneracióndeSeñal

                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[0 Intervalo])
                ylim(app.grafica,[0-(A+.5) A+.5]) %Grafica
            elseif Sen == 9
                title(app.grafica,'Señal Senoidal')
            
                A= Amplitud; %Amplitud
                T = Periodos; %Número de Periodos a Graficar
                f = 1/(Intervalo/T)
                duty= 50; %Ciclo útil
                t=0:Intervalo;%TiempodeSimulación
                x=A*sin(2*pi*f*t)%GeneracióndeSeñal

                if(Tipo == 'Continua')
                  
                    plot(app.grafica,t,x)
                    
                else
                    
                    stem(app.grafica,t,x)

                end
                grid(app.grafica,"on")
                xlim(app.grafica,[0 Intervalo])
                ylim(app.grafica,[0-(A+.5) A+.5]) %Grafica
            else
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.PRIMEREXAMENPARCIALLabel = uilabel(app.UIFigure);
            app.PRIMEREXAMENPARCIALLabel.Text = 'PRIMER EXAMEN PARCIAL';
            app.GENERADORDESEALESLabel = uilabel(app.UIFigure);
            app.GENERADORDESEALESLabel.Text = 'GENERADOR DE SEÑALES';
            app.TipodesealDropDownLabel = uilabel(app.UIFigure);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.UIFigure);
            app.TipodesealDropDown.Items = {'Escalón unitario', 'Escalón', 'Impulso Unitario', 'Rampa Unitaria', 'Exponencial Compleja', 'Exponencial Reales', 'Cuadrada', 'Diente de Sierra', 'Senoidal'};
            app.TipodesealDropDown.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.TipodesealDropDown.Value = 1;
            app.GraficacinButtonGroup = uibuttongroup(app.UIFigure);
            app.GraficacinButtonGroup.Title = 'Graficación';
            app.ContinuaButton = uiradiobutton(app.GraficacinButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.DiscretaButton = uiradiobutton(app.GraficacinButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField.Value = 1;
            app.PeriodoEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodoEditFieldLabel.Text = 'Periodo';
            app.PeriodoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodoEditField.Value = 1;
            app.GraficarSealButton = uibutton(app.UIFigure, 'push');
            app.GraficarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarSealButtonPushed, true);
            app.GraficarSealButton.Text = 'Graficar Señal';
            app.IntervaloEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloEditFieldLabel.Text = 'Intervalo';
            app.IntervaloEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervaloEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = examen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end